classdef Project_Kelompok_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InterpolasiTab                  matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        PolinomialRangragePanel         matlab.ui.container.Panel
        L3EditField                     matlab.ui.control.NumericEditField
        L3EditFieldLabel                matlab.ui.control.Label
        F3xEditField                    matlab.ui.control.NumericEditField
        F3xLabel                        matlab.ui.control.Label
        L2EditField                     matlab.ui.control.NumericEditField
        L2EditFieldLabel                matlab.ui.control.Label
        L1EditField                     matlab.ui.control.NumericEditField
        L1EditFieldLabel                matlab.ui.control.Label
        L0EditField                     matlab.ui.control.NumericEditField
        L0EditFieldLabel                matlab.ui.control.Label
        BedaNewtonPanel                 matlab.ui.container.Panel
        b3EditField                     matlab.ui.control.NumericEditField
        b3EditFieldLabel                matlab.ui.control.Label
        c2EditField                     matlab.ui.control.NumericEditField
        c2EditFieldLabel                matlab.ui.control.Label
        b2EditField_2                   matlab.ui.control.NumericEditField
        b2EditField_2Label              matlab.ui.control.Label
        d1EditField                     matlab.ui.control.NumericEditField
        d1EditFieldLabel                matlab.ui.control.Label
        c1EditField                     matlab.ui.control.NumericEditField
        c1EditFieldLabel                matlab.ui.control.Label
        FxEditField                     matlab.ui.control.NumericEditField
        FxLabel                         matlab.ui.control.Label
        b1EditField_2                   matlab.ui.control.NumericEditField
        b1EditField_2Label              matlab.ui.control.Label
        b0EditField_2                   matlab.ui.control.NumericEditField
        b0EditField_2Label              matlab.ui.control.Label
        LinearKuadratikPanel            matlab.ui.container.Panel
        KuadratikF2xEditField           matlab.ui.control.NumericEditField
        KuadratikF2xEditFieldLabel      matlab.ui.control.Label
        LinearF1xEditField              matlab.ui.control.NumericEditField
        LinearF1xEditFieldLabel         matlab.ui.control.Label
        b2EditField                     matlab.ui.control.NumericEditField
        b2EditFieldLabel                matlab.ui.control.Label
        b1EditField                     matlab.ui.control.NumericEditField
        b1EditFieldLabel                matlab.ui.control.Label
        b0EditField                     matlab.ui.control.NumericEditField
        b0EditFieldLabel                matlab.ui.control.Label
        HasilPerhitunganLabel           matlab.ui.control.Label
        KalkulatorInterpolasiLabel      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PolinomialLagrangeButton        matlab.ui.control.RadioButton
        BedaNewtonButton                matlab.ui.control.RadioButton
        LinearKuadratikButton           matlab.ui.control.RadioButton
        fx3EditField                    matlab.ui.control.NumericEditField
        fx3EditFieldLabel               matlab.ui.control.Label
        fx2EditField                    matlab.ui.control.NumericEditField
        fx2EditFieldLabel               matlab.ui.control.Label
        x3EditField                     matlab.ui.control.NumericEditField
        x3EditFieldLabel                matlab.ui.control.Label
        x2EditField                     matlab.ui.control.NumericEditField
        x2EditFieldLabel                matlab.ui.control.Label
        fx1EditField                    matlab.ui.control.NumericEditField
        fx1EditFieldLabel               matlab.ui.control.Label
        fx0EditField                    matlab.ui.control.NumericEditField
        fx0EditFieldLabel               matlab.ui.control.Label
        MasukkandataLabel               matlab.ui.control.Label
        KeluarButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        HitungButton                    matlab.ui.control.Button
        PilihMetodeLabel                matlab.ui.control.Label
        x1EditField                     matlab.ui.control.NumericEditField
        x1EditFieldLabel                matlab.ui.control.Label
        x0EditField                     matlab.ui.control.NumericEditField
        x0EditFieldLabel                matlab.ui.control.Label
        PerkiraanXEditField             matlab.ui.control.NumericEditField
        PerkiraanXLabel                 matlab.ui.control.Label
        IntegrasiNumerikTab             matlab.ui.container.Tab
        KalkulatorIntegrasiNumerikLabel  matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        GalatEditField                  matlab.ui.control.NumericEditField
        GalatEditFieldLabel             matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        ILabel                          matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        textbfTabelNilaix_idanf_iLabel  matlab.ui.control.Label
        HasilPerhitunganLabel_2         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Panel_6                         matlab.ui.container.Panel
        NilaiEksakIEditField            matlab.ui.control.NumericEditField
        NilaiEksakIEditFieldLabel       matlab.ui.control.Label
        AlertLabel                      matlab.ui.control.Label
        FungsiFxEditField               matlab.ui.control.EditField
        FungsiFxEditFieldLabel          matlab.ui.control.Label
        BatasAtasbEditField             matlab.ui.control.NumericEditField
        BatasAtasbEditFieldLabel        matlab.ui.control.Label
        PanjangIntervalhEditField       matlab.ui.control.NumericEditField
        PanjangIntervalhEditFieldLabel  matlab.ui.control.Label
        BanyakIntervalnEditField        matlab.ui.control.NumericEditField
        hLabel                          matlab.ui.control.Label
        BatasBawahaEditField            matlab.ui.control.NumericEditField
        BatasBawahaEditField_3Label     matlab.ui.control.Label
        IsisalahsatunatauhLabel         matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Simpson38Button                 matlab.ui.control.RadioButton
        Simpson13Button                 matlab.ui.control.RadioButton
        TrapeziumButton                 matlab.ui.control.RadioButton
        MasukkandataLabel_2             matlab.ui.control.Label
        KeluarButton_2                  matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        HitungButton_2                  matlab.ui.control.Button
        MetodeIntegrasiLabel            matlab.ui.control.Label
        PersamaanDiferensialTab         matlab.ui.container.Tab
        KalkulatorPersamaanDiferensialLabel  matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        GalatEditField_2                matlab.ui.control.NumericEditField
        GalatEditField_2Label           matlab.ui.control.Label
        IEditField_2                    matlab.ui.control.NumericEditField
        IEditField_2Label               matlab.ui.control.Label
        TabelNilaixyLabel               matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        HasilPerhitunganLabel_3         matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        Panel_8                         matlab.ui.container.Panel
        XnEditField                     matlab.ui.control.NumericEditField
        XnEditFieldLabel                matlab.ui.control.Label
        AlertLabel_2                    matlab.ui.control.Label
        PersamaandydxfxyEditField       matlab.ui.control.EditField
        PersamaandydxfxyEditFieldLabel  matlab.ui.control.Label
        X0EditField                     matlab.ui.control.NumericEditField
        X0Label                         matlab.ui.control.Label
        hEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        Y0EditField                     matlab.ui.control.NumericEditField
        Y0EditFieldLabel                matlab.ui.control.Label
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        RungeKutta4Button               matlab.ui.control.RadioButton
        EulerButton                     matlab.ui.control.RadioButton
        MasukkandataLabel_3             matlab.ui.control.Label
        KeluarButton_3                  matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        HitungButton_3                  matlab.ui.control.Button
        PilihMetodeLabel_2              matlab.ui.control.Label
        MetodeNumerikKelompok7Label     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
        
        end

        % Button pushed function: HitungButton
        function HitungButtonPushed(app, event)
            x = app.PerkiraanXEditField.Value;
            x0 = app.x0EditField.Value;
            x1 = app.x1EditField.Value;
            x2 = app.x2EditField.Value;
            x3 = app.x3EditField.Value;

            fx0 = app.fx0EditField.Value;
            fx1 = app.fx1EditField.Value;
            fx2 = app.fx2EditField.Value;
            fx3 = app.fx3EditField.Value;

            
            b0 = fx0;
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LinearKuadratikButton
                f1x = fx0 + ((fx1 - fx0 )/( x1 - x0)) * (x - x0);
                
                b1 = (fx1 - fx0) / (x1 - x0);
                b2 = (fx2 - fx1) / ((x2-x1) / (x2-x0)) - b1;
                
                f2x = b0 + (b1 *(x-x0)) + (b2 *(x-x0) * (x-x1));
        
                app.LinearF1xEditField.Value = f1x;
                app.b0EditField.Value = b0;
                app.b1EditField.Value = b1;
                app.b2EditField.Value = b2;
                app.KuadratikF2xEditField.Value = f2x;

            elseif selectedButton == app.BedaNewtonButton
                b1 = (fx1 - fx0) / (x1 - x0);
                c1 = (fx2 - fx1) / (x2 - x1);
                d1 = (fx3 - fx2) / (x3 - x2);
                
                b2 = (c1 - b1) / (x2 - x0);
                c2 = (d1 - c1) / (x3 - x1);
                b3 = (c2 - b2) / (x3 - x0);

                fx = b0 ...
                 + b1*(x - x0) ...
                 + b2*(x - x0)*(x - x1) ...
                 + b3*(x - x0)*(x - x1)*(x - x2);


                app.b0EditField_2.Value = b0;
                app.b1EditField_2.Value = b1;
                app.c1EditField.Value = c1;
                app.d1EditField.Value = d1;
                app.b2EditField_2.Value = b2;
                app.c2EditField.Value = c2;
                app.b3EditField.Value = b3;
                app.FxEditField.Value = fx;

            else
                l0 = ((x - x1) / (x0 - x1)) *...
                    ((x - x2) / (x0 - x2)) *...
                    ((x - x3) / (x0 - x3));
                
                l1 = ((x - x0) / (x1 - x0)) *...
                    ((x - x2) / (x1 - x2)) *...
                    ((x - x3) / (x1 - x3));

                l2 = ((x - x0) / (x2 - x0)) *...
                    ((x - x1) / (x2 - x1)) *...
                    ((x - x3) / (x2 - x3));

                l3 = ((x - x0) / (x3 - x0)) *...
                    ((x - x1) / (x3 - x1)) *...
                    ((x - x2) / (x3 - x2));

                f3x = l0 * fx0 + l1 * fx1 + l2 * fx2 + l3 * fx3;

                app.L0EditField.Value = l0;
                app.L1EditField.Value = l1;
                app.L2EditField.Value = l2;
                app.L3EditField.Value = l3;
                app.F3xEditField.Value = f3x;


            end


% ======================================================
% Nama Program   : Kalkulator Interpolasi
% Deskripsi      : Program ini digunakan untuk menghitung nilai interpolasi
%                  dengan berbagai metode, seperti Linear, Kuadratik,
%                  Newton, dan Lagrange.
% Dibuat oleh    : Yuriko Farhan
% Tanggal Dibuat : 21 Juni 2025
% ======================================================


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Reset semua inputan
            app.PerkiraanXEditField.Value = 0;
            app.x0EditField.Value = 0;
            app.x1EditField.Value = 0;
            app.x2EditField.Value = 0;
            app.x3EditField.Value = 0;
        
            app.fx0EditField.Value = 0;
            app.fx1EditField.Value = 0;
            app.fx2EditField.Value = 0;
            app.fx3EditField.Value = 0;
        
            % Reset hasil Linear/Kuadratik
            app.LinearF1xEditField.Value = 0;
            app.b0EditField.Value = 0;
            app.b1EditField.Value = 0;
            app.b2EditField.Value = 0;
            app.KuadratikF2xEditField.Value = 0;
        
            % Reset hasil Beda Newton
            app.b0EditField_2.Value = 0;
            app.b1EditField_2.Value = 0;
            app.c1EditField.Value = 0;
            app.d1EditField.Value = 0;
            app.b2EditField_2.Value = 0;
            app.c2EditField.Value = 0;
            app.b3EditField.Value = 0;
            app.FxEditField.Value = 0;
        
            % Reset hasil Lagrange
            app.L0EditField.Value = 0;
            app.L1EditField.Value = 0;
            app.L2EditField.Value = 0;
            app.L3EditField.Value = 0;
            app.F3xEditField.Value = 0;
        
            % Reset pilihan metode (opsional, kembalikan ke Linear/Kuadratik)
            app.ButtonGroup.SelectedObject = app.LinearKuadratikButton;
            

        end

        % Button pushed function: KeluarButton
        function KeluarButtonPushed(app, event)
      
                delete(app.UIFigure);
            
        end

        % Callback function
        function HitungButton_2Pushed(app, event)
              x = app.PerkiraanJarakEditField_2.Value;
            x_1 = app.x0EditField.Value;
            x_2 = app.XEditField_2.Value;
            x_3 = app.XEditField_3.Value;
            x_4 = app.XEditField_4.Value;
            x_5 = app.XEditField_5.Value;
            
            y_1 = app.x1EditField.Value;
            y_2 = app.YEditField_2.Value;
            y_3 = app.YEditField_3.Value;
            y_4 = app.YEditField_4.Value;
            y_5 = app.YEditField_5.Value;
            
            x_data = [x_1 x_2 x_3 x_4 x_5];
            y_data = [y_1 y_2 y_3 y_4 y_5];
            
            for i = 1:length(x_data)-1
                if x <= x_data(i)
                    x0 = x_data(i-2);
                    fx0 = y_data(i-2);
                    x1 = x_data(i-1);
                    fx1 = y_data(i-1);
                    x2 = x_data(i);
                    fx2 = y_data(i);
                    break;
                end
                
            end
            
            b0 = fx0;
            b1 = (fx1 - fx0) / (x1 - x0);
            b2 = (fx2 - fx1) / ((x2-x1) / (x2-x0)) - b1;
            
            f2x = b0 + (b1 *(x-x0)) + (b2 *(x-x0) * (x-x1));

            app.XEditField_2.Value = x;
            app.x2EditField.Value = x0;
            app.f0EditField.Value = x1;
            app.x1EditField.Value = x2;
            app.Fx0EditField_2.Value = fx0;
            app.Fx1EditField_2.Value = fx1;
            app.Fx2EditField.Value = fx2;
            app.KuadratikF2xEditField.Value = f2x;
            app.b0EditField.Value = b0;
            app.b1EditField.Value = b1;
            app.b2EditField.Value = b2;
            
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
           
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
             if selectedButton ~= app.LinearKuadratikButton
                app.x3EditField.Visible = true;
                app.x3EditFieldLabel.Visible = true;
                app.fx3EditField.Visible = true;
                app.fx3EditFieldLabel.Visible = true;

            else
                % Kalau LinearKuadratikButton dipilih
                app.x3EditField.Visible = false;
                app.x3EditFieldLabel.Visible = false;
                app.fx3EditField.Visible = false;
                app.fx3EditFieldLabel.Visible = false;

            end
        end

        % Button pushed function: HitungButton_2
        function HitungButton_2Pushed2(app, event)
           % Ambil input dari komponen UI
            fxStr = app.FungsiFxEditField.Value;
            a = app.BatasBawahaEditField.Value;
            b = app.BatasAtasbEditField.Value;
            n = app.BanyakIntervalnEditField.Value;
            h = app.PanjangIntervalhEditField.Value;
            I_eksak = app.NilaiEksakIEditField.Value;
            

            fxStr = strrep(fxStr, '^', '.^'); % Mendukung pangkat elemenwise
            fxStr = regexprep(fxStr, '(?<=\d)(x)', '*$1');             % 3x -> 3*x
            fxStr = regexprep(fxStr, '(x)(?=\d)', '$1*');              % x2 -> x*2
            fxStr = regexprep(fxStr, '(?<=\))(\s*)(x)', '*$2');        % )x -> )*x
            fxStr = regexprep(fxStr, '√\s*([a-zA-Z0-9_]+)', 'sqrt($1)'); % √x -> sqrt(x)
            fxStr = regexprep(fxStr, '(?<=\d)\s*sqrt', '*sqrt');       % 2√x -> 2*sqrt(x)
            
            % Coba ubah string jadi fungsi anonim
            try
                f = str2func(['@(x)', fxStr]);
                test = f(1); % uji coba fungsi
            catch
                app.AlertLabel.Text = 'Error: Fungsi tidak valid';
                return;
            end
            
            % Buat array x dan evaluasi fungsi
            x = linspace(a, b, n + 1);
            fx = f(x);
            
            % Ambil metode integrasi yang dipilih
            metode = app.ButtonGroup_2.SelectedObject.Text;
            
            switch metode
                case 'Trapezium'
                    I = h * ((fx(1) + fx(end)) / 2 + sum(fx(2:end-1)));
            
                case 'Simpson 1/3'
                    if mod(n, 2) ~= 0
                        app.AlertLabel.Text = 'Error: n harus genap untuk metode Simpson 1/3';
                        return;
                    end
                    I = h / 3 * (fx(1) + fx(end) + ...
                                 4 * sum(fx(2:2:end-1)) + ...
                                 2 * sum(fx(3:2:end-2)));
            
                case 'Simpson 3/8'
                    if mod(n, 3) ~= 0
                        app.AlertLabel.Text = 'Error: n harus kelipatan 3 untuk metode Simpson 3/8';
                        return;
                    end
                    sum1 = sum(fx(2:end-1));                  % Semua titik tengah
                    sum2 = sum(fx(4:3:end-1));                % Titik kelipatan 3
                    sum1 = sum1 - sum2;                       % Titik bukan kelipatan 3
                    I = (3 * h / 8) * (fx(1) + 3 * sum1 + 2 * sum2 + fx(end));
            
                otherwise
                    app.AlertLabel.Text = 'Metode tidak dikenali';
                    return;
            end
            
            app.AlertLabel.Text = '';

            % Tampilkan hasil di tabel
            app.UITable.Data = table(x', fx', 'VariableNames', {'x', 'f(x)'});
           
            % Tampilkan hasil integral
            app.IEditField.Value = I;
            app.GalatEditField.Value = abs((I_eksak - I) / I_eksak) * 100; % Belum dihitung galatnya
            
            % Siapkan titik-titik untuk kurva fungsi
            xPlot = linspace(a, b, 1000);
            yPlot = f(xPlot);
            
            % Titik simpul (partisi)
            xNodes = linspace(a, b, n+1);
            yNodes = f(xNodes);
            
            % Bersihkan axes
            cla(app.UIAxes);
            
            % Warnai area di bawah kurva
            fill(app.UIAxes, [xPlot, fliplr(xPlot)], [zeros(size(yPlot)), fliplr(yPlot)], ...
                [0.8, 0.9, 1], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
            hold(app.UIAxes, 'on');
            
            % Plot kurva fungsi
            plot(app.UIAxes, xPlot, yPlot, 'b-', 'LineWidth', 2);
            
            % Plot titik-titik partisi
            plot(app.UIAxes, xNodes, yNodes, 'ro', 'MarkerFaceColor', 'r');
            
            % Tambahkan label angka di setiap titik
            for i = 1:length(xNodes)
                % Tampilkan x_i dan nilai f(x_i)
                labelStr = sprintf('x_{%d}=%.2f\nf=%.2f', i-1, xNodes(i), yNodes(i));
                text(app.UIAxes, xNodes(i), yNodes(i) + 5, labelStr, ...
                    'HorizontalAlignment', 'center', 'FontSize', 9, 'Color', 'k');
            end
            
            % Label sumbu dan judul
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Grafik Fungsi dan Titik Partisi');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');


% =============================================================
% Nama Program   : Kalkulator Integrasi Numerik
% Deskripsi      : Program ini digunakan untuk menghitung integral
%                  tentu dari fungsi f(x) secara numerik menggunakan
%                  metode Trapezoid dan Simpson.
% Dibuat oleh    : Yuriko Farhan
% Tanggal Dibuat : 21 Juni 2025
% =============================================================


        end

        % Value changed function: BanyakIntervalnEditField
        function BanyakIntervalnEditFieldValueChanged(app, event)
            n = app.BanyakIntervalnEditField.Value;
            h = app.PanjangIntervalhEditField.Value;
            b = app.BatasAtasbEditField.Value;
            a = app.BatasBawahaEditField.Value;
            if ~isempty(h) || h == 0
                app.PanjangIntervalhEditField.Value = (b - a) / n;
            end
        end

        % Value changed function: PanjangIntervalhEditField
        function PanjangIntervalhEditFieldValueChanged(app, event)
            h = app.PanjangIntervalhEditField.Value;
            n = app.BanyakIntervalnEditField.Value;
            b = app.BatasAtasbEditField.Value;
            a = app.BatasBawahaEditField.Value;
            if ~isempty(n) || n == 0
                app.BanyakIntervalnEditField.Value = (b - a) / h;
            end
        end

        % Button pushed function: KeluarButton_2
        function KeluarButton_2Pushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            % 1. Bersihkan grafik
            cla(app.UIAxes);                             % Clear axes
            title(app.UIAxes, '');                       % Kosongkan judul
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            
            % 2. Kosongkan tabel
            app.UITable.Data = table([], [], 'VariableNames', {'x', 'f(x)'});
            
            % 3. Kosongkan hasil integral dan galat
            app.IEditField.Value = 0;
            app.GalatEditField.Value = 0;
            
            % 4. Kosongkan peringatan atau label alert
            app.AlertLabel.Text = '';
            
            % 5. Reset semua input ke default
            app.FungsiFxEditField.Value = '';
            app.NilaiEksakIEditField.Value = 0;
            app.BatasBawahaEditField.Value = 0;
            app.BatasAtasbEditField.Value = 0;
            app.BanyakIntervalnEditField.Value = 0;
            app.PanjangIntervalhEditField.Value = 0;
            
        end

        % Button pushed function: HitungButton_3
        function HitungButton_3Pushed(app, event)
         
            fStr = strtrim(app.PersamaandydxfxyEditField.Value);
            x0 = app.X0EditField.Value;
            y0 = app.Y0EditField.Value;
            xn = app.XnEditField.Value;
            h  = app.hEditField.Value;
            
            % Validasi input
            if isempty(fStr) || isnan(x0) || isnan(y0) || isnan(xn) || isnan(h) || h <= 0
                app.AlertLabel_2.Text = 'Input tidak lengkap atau tidak valid.';
                return;
            end
            
            % Format string agar MATLAB bisa baca
            fStr = strrep(fStr, '−', '-');
            fStr = regexprep(fStr, '(\d)([a-zA-Z])', '$1.*$2');
            fStr = regexprep(fStr, '([a-zA-Z])(\d)', '$1.*$2');
            fStr = regexprep(fStr, '([a-zA-Z0-9])\^', '$1.^');
            
            % Ubah string jadi fungsi f(x)
            try
                fxy = str2func(['@(x) ' fStr]);
            catch
                app.AlertLabel_2.Text = 'Fungsi tidak valid.';
                return;
            end
            
            % Inisialisasi nilai-nilai
            xVals = x0:h:xn;
            N = length(xVals);
            yEuler = zeros(1, N);
            yRK4 = zeros(1, N);
            yEuler(1) = y0;
            yRK4(1) = y0;
            
            % Metode Euler
            for i = 1:N-1
                yEuler(i+1) = yEuler(i) + h * fxy(xVals(i));
            end
            
            % Metode RK4
            for i = 1:N-1
                x = xVals(i);
                y = yRK4(i);
                k1 = fxy(x);
                k2 = fxy(x + h/2);
                k3 = fxy(x + h/2);
                k4 = fxy(x + h);
                yRK4(i+1) = y + h/6 * (k1 + 2*k2 + 2*k3 + k4);
            end
            
            % Hitung solusi eksak (integrasi manual)
            try
                polySym = str2sym(fStr);
                [coeffs_, terms_] = coeffs(polySym, 'All');
                powers = zeros(size(terms_));
                for i = 1:length(terms_)
                    termStr = char(terms_(i));
                    xPower = regexp(termStr, 'x\^(\d+)', 'tokens');
                    if ~isempty(xPower)
                        powers(i) = str2double(xPower{1}{1});
                    elseif contains(termStr, 'x')
                        powers(i) = 1;
                    else
                        powers(i) = 0;
                    end
                end
                coeffsVal = double(coeffs_);
                
                yEksakFunc = @(x) 0;
                for i = 1:length(coeffsVal)
                    a = coeffsVal(i);
                    n = powers(i);
                    yEksakFunc_i = @(x) a/(n+1) * x.^(n+1);
                    yEksakFuncOld = yEksakFunc;
                    yEksakFunc = @(x) yEksakFuncOld(x) + yEksakFunc_i(x);
                end
                
                % Tambahkan konstanta C agar yEksak(x0) = y0
                C = y0 - yEksakFunc(x0);
                yEksakFuncOld = yEksakFunc;
                yEksakFunc = @(x) yEksakFuncOld(x) + C;
                
            catch
                app.AlertLabel_2.Text = 'Solusi eksak hanya mendukung f(x) polinomial.';
                return;
            end
            
            % Pilih metode
            metode = app.ButtonGroup_3.SelectedObject.Text;
            if strcmp(metode, 'Euler')
                yNumerik = yEuler;
                labelMetode = 'yEuler';
            else
                yNumerik = yRK4;
                labelMetode = 'yRK4';
            end
            
            % Hitung solusi eksak dan galat
            yEksak = arrayfun(yEksakFunc, xVals);
            galatPersen = abs((yNumerik - yEksak) ./ yEksak) * 100;
            galatPersen(yEksak == 0) = 100;
            
            % Format output untuk tabel
            xStr         = strrep(string(xVals'), '.', ',');
            yNumStr      = strrep(string(round(yNumerik', 3)), '.', ',');
            yEksakStr    = strrep(string(round(yEksak', 3)), '.', ',');
            galatRounded = round(galatPersen', 2);
            galatStr     = strcat(strrep(string(galatRounded), '.', ','), '%');
            
            TabelFormat = table(xStr, yNumStr, yEksakStr, galatStr, ...
                'VariableNames', {'x_i', labelMetode, 'yEksak', 'Galat'});
            
            app.UITable_2.Data = TabelFormat;
            
            % === Plot hasil dengan arsiran biru transparan ===
            cla(app.UIAxes_2);
            
            % Plot area biru transparan di bawah kurva numerik
            fillX = [xVals, fliplr(xVals)];
            fillY = [zeros(size(yNumerik)), fliplr(yNumerik)];
            fill(app.UIAxes_2, fillX, fillY, [0 0 1], ...
                'FaceAlpha', 0.2, 'EdgeColor', 'none');  % Transparan biru 20%
            
            hold(app.UIAxes_2, 'on');
            
            % Garis metode numerik (biru)
            plot(app.UIAxes_2, xVals, yNumerik, '-o', ...
                'LineWidth', 2, 'Color', [0 0 1], 'DisplayName', labelMetode);
            
            % Garis solusi eksak (merah)
            plot(app.UIAxes_2, xVals, yEksak, '--', ...
                'LineWidth', 2, 'Color', [1 0 0], 'DisplayName', 'yEksak');
            
            hold(app.UIAxes_2, 'off');
            
            legend(app.UIAxes_2, 'show');
            title(app.UIAxes_2, ['Solusi ODE - ', metode]);
            xlabel(app.UIAxes_2, 'x');
            ylabel(app.UIAxes_2, 'y');
            grid(app.UIAxes_2, 'on');
            
            % Kosongkan alert jika berhasil
            app.AlertLabel_2.Text = '';


% ================================================
% Nama Program   : Kalkulator Persamaan Diferensial
% Deskripsi      : Program ini menyelesaikan ODE
%                  menggunakan metode Euler dan RK4
% Dibuat oleh    : Yuriko Farhan
% Tanggal Dibuat : 21 Juni 2025
% ================================================
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            % Reset input field
            app.PersamaandydxfxyEditField.Value = '';
            app.X0EditField.Value = 0;
            app.Y0EditField.Value = 0;
            app.XnEditField.Value = 0;
            app.hEditField.Value = 0;
        
            % Reset alert
            app.AlertLabel_2.Text = '';
        
            % Kosongkan tabel hasil
            app.UITable_2.Data = {};
        
            % Kosongkan plot
            cla(app.UIAxes_2);
            title(app.UIAxes_2, '');
            xlabel(app.UIAxes_2, '');
            ylabel(app.UIAxes_2, '');
            grid(app.UIAxes_2, 'off');
        
            % Reset pilihan metode (opsional)
            app.ButtonGroup_3.SelectedObject = app.EulerButton; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'hsv');
            app.UIFigure.Name = 'MATLAB App';
            app.MetodeNumerikKelompok7Label = uilabel(app.UIFigure);
            app.MetodeNumerikKelompok7Label.Text = 'Metode Numerik -  Kelompok 7';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterpolasiTab = uitab(app.TabGroup);
            app.InterpolasiTab.Title = 'Interpolasi';
            app.Panel = uipanel(app.InterpolasiTab);
            app.PerkiraanXLabel = uilabel(app.Panel);
            app.PerkiraanXLabel.Text = 'Perkiraan X :';
            app.PerkiraanXEditField = uieditfield(app.Panel, 'numeric');
            app.PerkiraanXEditField.AllowEmpty = 'on';
            app.PerkiraanXEditField.Value = 97.66;
            app.x0EditFieldLabel = uilabel(app.Panel);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.Panel, 'numeric');
            app.x0EditField.Placeholder = '...';
            app.x0EditField.Value = 93;
            app.x1EditFieldLabel = uilabel(app.Panel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.Panel, 'numeric');
            app.x1EditField.Placeholder = '...';
            app.x1EditField.Value = 96.2;
            app.PilihMetodeLabel = uilabel(app.Panel);
            app.PilihMetodeLabel.Text = '🔘 Pilih Metode :';
            app.HitungButton = uibutton(app.Panel, 'push');
            app.HitungButton.ButtonPushedFcn = createCallbackFcn(app, @HitungButtonPushed, true);
            app.HitungButton.Text = 'Hitung';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.KeluarButton = uibutton(app.Panel, 'push');
            app.KeluarButton.ButtonPushedFcn = createCallbackFcn(app, @KeluarButtonPushed, true);
            app.KeluarButton.Text = 'Keluar';
            app.MasukkandataLabel = uilabel(app.Panel);
            app.MasukkandataLabel.Text = 'Masukkan data :';
            app.fx0EditFieldLabel = uilabel(app.Panel);
            app.fx0EditFieldLabel.Text = 'f(x0)';
            app.fx0EditField = uieditfield(app.Panel, 'numeric');
            app.fx0EditField.ValueDisplayFormat = '%11.8g';
            app.fx0EditField.Placeholder = '...';
            app.fx0EditField.Value = 11.38;
            app.fx1EditFieldLabel = uilabel(app.Panel);
            app.fx1EditFieldLabel.Text = 'f(x1)';
            app.fx1EditField = uieditfield(app.Panel, 'numeric');
            app.fx1EditField.ValueDisplayFormat = '%11.8g';
            app.fx1EditField.Placeholder = '...';
            app.fx1EditField.Value = 12.8;
            app.x2EditFieldLabel = uilabel(app.Panel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.Panel, 'numeric');
            app.x2EditField.Placeholder = '...';
            app.x2EditField.Value = 100;
            app.x3EditFieldLabel = uilabel(app.Panel);
            app.x3EditFieldLabel.Visible = 'off';
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.Panel, 'numeric');
            app.x3EditField.Visible = 'off';
            app.x3EditField.Placeholder = '...';
            app.x3EditField.Value = 104.2;
            app.fx2EditFieldLabel = uilabel(app.Panel);
            app.fx2EditFieldLabel.Text = 'f(x2)';
            app.fx2EditField = uieditfield(app.Panel, 'numeric');
            app.fx2EditField.ValueDisplayFormat = '%11.8g';
            app.fx2EditField.Placeholder = '...';
            app.fx2EditField.Value = 14.7;
            app.fx3EditFieldLabel = uilabel(app.Panel);
            app.fx3EditFieldLabel.Visible = 'off';
            app.fx3EditFieldLabel.Text = 'f(x3)';
            app.fx3EditField = uieditfield(app.Panel, 'numeric');
            app.fx3EditField.ValueDisplayFormat = '%11.8g';
            app.fx3EditField.Visible = 'off';
            app.fx3EditField.Placeholder = '...';
            app.fx3EditField.Value = 17.07;
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.LinearKuadratikButton = uiradiobutton(app.ButtonGroup);
            app.LinearKuadratikButton.Text = 'Linear & Kuadratik';
            app.LinearKuadratikButton.Value = true;
            app.BedaNewtonButton = uiradiobutton(app.ButtonGroup);
            app.BedaNewtonButton.Text = 'Beda Newton';
            app.PolinomialLagrangeButton = uiradiobutton(app.ButtonGroup);
            app.PolinomialLagrangeButton.Text = 'Polinomial Lagrange';
            app.KalkulatorInterpolasiLabel = uilabel(app.InterpolasiTab);
            app.KalkulatorInterpolasiLabel.Text = 'Kalkulator Interpolasi';
            app.Panel_5 = uipanel(app.InterpolasiTab);
            app.HasilPerhitunganLabel = uilabel(app.Panel_5);
            app.HasilPerhitunganLabel.Text = 'Hasil Perhitungan';
            app.LinearKuadratikPanel = uipanel(app.Panel_5);
            app.LinearKuadratikPanel.Title = 'Linear & Kuadratik';
            app.b0EditFieldLabel = uilabel(app.LinearKuadratikPanel);
            app.b0EditFieldLabel.Text = 'b0';
            app.b0EditField = uieditfield(app.LinearKuadratikPanel, 'numeric');
            app.b0EditField.ValueDisplayFormat = '%11.6g';
            app.b0EditField.Placeholder = '...';
            app.b1EditFieldLabel = uilabel(app.LinearKuadratikPanel);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.LinearKuadratikPanel, 'numeric');
            app.b1EditField.ValueDisplayFormat = '%11.6g';
            app.b1EditField.Placeholder = '...';
            app.b2EditFieldLabel = uilabel(app.LinearKuadratikPanel);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.LinearKuadratikPanel, 'numeric');
            app.b2EditField.ValueDisplayFormat = '%11.6g';
            app.b2EditField.Placeholder = '...';
            app.LinearF1xEditFieldLabel = uilabel(app.LinearKuadratikPanel);
            app.LinearF1xEditFieldLabel.Text = 'Linear : F1x';
            app.LinearF1xEditField = uieditfield(app.LinearKuadratikPanel, 'numeric');
            app.LinearF1xEditField.ValueDisplayFormat = '%11.6g';
            app.LinearF1xEditField.Placeholder = '...';
            app.KuadratikF2xEditFieldLabel = uilabel(app.LinearKuadratikPanel);
            app.KuadratikF2xEditFieldLabel.Text = 'Kuadratik : F2x';
            app.KuadratikF2xEditField = uieditfield(app.LinearKuadratikPanel, 'numeric');
            app.KuadratikF2xEditField.ValueDisplayFormat = '%11.6g';
            app.KuadratikF2xEditField.Placeholder = '...';
            app.BedaNewtonPanel = uipanel(app.Panel_5);
            app.BedaNewtonPanel.Title = 'Beda Newton';
            app.b0EditField_2Label = uilabel(app.BedaNewtonPanel);
            app.b0EditField_2Label.Text = 'b0';
            app.b0EditField_2 = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.b0EditField_2.ValueDisplayFormat = '%6.6g';
            app.b0EditField_2.Placeholder = '...';
            app.b1EditField_2Label = uilabel(app.BedaNewtonPanel);
            app.b1EditField_2Label.Text = 'b1';
            app.b1EditField_2 = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.b1EditField_2.ValueDisplayFormat = '%6.6g';
            app.b1EditField_2.Placeholder = '...';
            app.FxLabel = uilabel(app.BedaNewtonPanel);
            app.FxLabel.Text = 'F(x) :';
            app.FxEditField = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.FxEditField.ValueDisplayFormat = '%11.6g';
            app.FxEditField.Placeholder = '...';
            app.c1EditFieldLabel = uilabel(app.BedaNewtonPanel);
            app.c1EditFieldLabel.Text = 'c1';
            app.c1EditField = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.c1EditField.ValueDisplayFormat = '%6.6g';
            app.c1EditField.Placeholder = '...';
            app.d1EditFieldLabel = uilabel(app.BedaNewtonPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.d1EditField.ValueDisplayFormat = '%6.6g';
            app.d1EditField.Placeholder = '...';
            app.b2EditField_2Label = uilabel(app.BedaNewtonPanel);
            app.b2EditField_2Label.Text = 'b2';
            app.b2EditField_2 = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.b2EditField_2.ValueDisplayFormat = '%6.6g';
            app.b2EditField_2.Placeholder = '...';
            app.c2EditFieldLabel = uilabel(app.BedaNewtonPanel);
            app.c2EditFieldLabel.Text = 'c2';
            app.c2EditField = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.c2EditField.ValueDisplayFormat = '%6.6g';
            app.c2EditField.Placeholder = '...';
            app.b3EditFieldLabel = uilabel(app.BedaNewtonPanel);
            app.b3EditFieldLabel.Text = 'b3';
            app.b3EditField = uieditfield(app.BedaNewtonPanel, 'numeric');
            app.b3EditField.ValueDisplayFormat = '%6.6g';
            app.b3EditField.Placeholder = '...';
            app.PolinomialRangragePanel = uipanel(app.Panel_5);
            app.PolinomialRangragePanel.Title = 'Polinomial Rangrage';
            app.L0EditFieldLabel = uilabel(app.PolinomialRangragePanel);
            app.L0EditFieldLabel.Text = 'L0';
            app.L0EditField = uieditfield(app.PolinomialRangragePanel, 'numeric');
            app.L0EditField.ValueDisplayFormat = '%11.6g';
            app.L0EditField.Placeholder = '...';
            app.L1EditFieldLabel = uilabel(app.PolinomialRangragePanel);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.PolinomialRangragePanel, 'numeric');
            app.L1EditField.ValueDisplayFormat = '%11.6g';
            app.L1EditField.Placeholder = '...';
            app.L2EditFieldLabel = uilabel(app.PolinomialRangragePanel);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.PolinomialRangragePanel, 'numeric');
            app.L2EditField.ValueDisplayFormat = '%11.6g';
            app.L2EditField.Placeholder = '...';
            app.F3xLabel = uilabel(app.PolinomialRangragePanel);
            app.F3xLabel.Text = 'F3(x) :';
            app.F3xEditField = uieditfield(app.PolinomialRangragePanel, 'numeric');
            app.F3xEditField.ValueDisplayFormat = '%11.10g';
            app.F3xEditField.Placeholder = '...';
            app.L3EditFieldLabel = uilabel(app.PolinomialRangragePanel);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.PolinomialRangragePanel, 'numeric');
            app.L3EditField.ValueDisplayFormat = '%11.6g';
            app.L3EditField.Placeholder = '...';
            app.IntegrasiNumerikTab = uitab(app.TabGroup);
            app.IntegrasiNumerikTab.Title = 'Integrasi Numerik';
            app.Panel_6 = uipanel(app.IntegrasiNumerikTab);
            app.MetodeIntegrasiLabel = uilabel(app.Panel_6);
            app.MetodeIntegrasiLabel.Text = '🔘 Metode Integrasi :';
            app.HitungButton_2 = uibutton(app.Panel_6, 'push');
            app.HitungButton_2.ButtonPushedFcn = createCallbackFcn(app, @HitungButton_2Pushed2, true);
            app.HitungButton_2.Text = 'Hitung';
            app.ResetButton_2 = uibutton(app.Panel_6, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.KeluarButton_2 = uibutton(app.Panel_6, 'push');
            app.KeluarButton_2.ButtonPushedFcn = createCallbackFcn(app, @KeluarButton_2Pushed, true);
            app.KeluarButton_2.Text = 'Keluar';
            app.MasukkandataLabel_2 = uilabel(app.Panel_6);
            app.MasukkandataLabel_2.Text = 'Masukkan data :';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_6);
            app.TrapeziumButton = uiradiobutton(app.ButtonGroup_2);
            app.TrapeziumButton.Text = 'Trapezium';
            app.TrapeziumButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.ButtonGroup_2);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.ButtonGroup_2);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.IsisalahsatunatauhLabel = uilabel(app.Panel_6);
            app.IsisalahsatunatauhLabel.Text = '*Isi salah satu (n atau h) :..';
            app.BatasBawahaEditField_3Label = uilabel(app.Panel_6);
            app.BatasBawahaEditField_3Label.Text = 'Batas Bawah (a) :';
            app.BatasBawahaEditField = uieditfield(app.Panel_6, 'numeric');
            app.BatasBawahaEditField.AllowEmpty = 'on';
            app.BatasBawahaEditField.Value = 1;
            app.hLabel = uilabel(app.Panel_6);
            app.hLabel.Text = 'Banyak Interval (n) :';
            app.BanyakIntervalnEditField = uieditfield(app.Panel_6, 'numeric');
            app.BanyakIntervalnEditField.AllowEmpty = 'on';
            app.BanyakIntervalnEditField.ValueChangedFcn = createCallbackFcn(app, @BanyakIntervalnEditFieldValueChanged, true);
            app.BanyakIntervalnEditField.Value = 4;
            app.PanjangIntervalhEditFieldLabel = uilabel(app.Panel_6);
            app.PanjangIntervalhEditFieldLabel.Text = 'Panjang Interval (h) :';
            app.PanjangIntervalhEditField = uieditfield(app.Panel_6, 'numeric');
            app.PanjangIntervalhEditField.AllowEmpty = 'on';
            app.PanjangIntervalhEditField.ValueChangedFcn = createCallbackFcn(app, @PanjangIntervalhEditFieldValueChanged, true);
            app.BatasAtasbEditFieldLabel = uilabel(app.Panel_6);
            app.BatasAtasbEditFieldLabel.Text = 'Batas Atas (b) :';
            app.BatasAtasbEditField = uieditfield(app.Panel_6, 'numeric');
            app.BatasAtasbEditField.AllowEmpty = 'on';
            app.BatasAtasbEditField.Value = 2;
            app.FungsiFxEditFieldLabel = uilabel(app.Panel_6);
            app.FungsiFxEditFieldLabel.Text = 'Fungsi F(x) :';
            app.FungsiFxEditField = uieditfield(app.Panel_6, 'text');
            app.FungsiFxEditField.Value = '3x^3+6x-5';
            app.AlertLabel = uilabel(app.Panel_6);
            app.AlertLabel.Text = '';
            app.NilaiEksakIEditFieldLabel = uilabel(app.Panel_6);
            app.NilaiEksakIEditFieldLabel.Text = 'Nilai Eksak (I) :';
            app.NilaiEksakIEditField = uieditfield(app.Panel_6, 'numeric');
            app.NilaiEksakIEditField.ValueDisplayFormat = '%11.6g';
            app.NilaiEksakIEditField.AllowEmpty = 'on';
            app.NilaiEksakIEditField.Value = 15.25;
            app.Panel_7 = uipanel(app.IntegrasiNumerikTab);
            app.UIAxes = uiaxes(app.Panel_7);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HasilPerhitunganLabel_2 = uilabel(app.Panel_7);
            app.HasilPerhitunganLabel_2.Text = 'Hasil Perhitungan';
            app.textbfTabelNilaix_idanf_iLabel = uilabel(app.Panel_7);
            app.textbfTabelNilaix_idanf_iLabel.Interpreter = 'latex';
            app.textbfTabelNilaix_idanf_iLabel.Text = '📋 \textbf{Tabel Nilai \(x_i\) dan \(f_i\)}';
            app.UITable = uitable(app.Panel_7);
            app.UITable.ColumnName = {'X'; 'F'};
            app.UITable.RowName = {};
            app.ILabel = uilabel(app.Panel_7);
            app.ILabel.Text = 'I :';
            app.IEditField = uieditfield(app.Panel_7, 'numeric');
            app.IEditField.ValueDisplayFormat = '%11.6g';
            app.IEditField.AllowEmpty = 'on';
            app.GalatEditFieldLabel = uilabel(app.Panel_7);
            app.GalatEditFieldLabel.Text = 'Galat :';
            app.GalatEditField = uieditfield(app.Panel_7, 'numeric');
            app.GalatEditField.ValueDisplayFormat = '%11.2g';
            app.GalatEditField.AllowEmpty = 'on';
            app.Label = uilabel(app.Panel_7);
            app.Label.Text = '%';
            app.KalkulatorIntegrasiNumerikLabel = uilabel(app.IntegrasiNumerikTab);
            app.KalkulatorIntegrasiNumerikLabel.Text = 'Kalkulator Integrasi Numerik';
            app.PersamaanDiferensialTab = uitab(app.TabGroup);
            app.PersamaanDiferensialTab.Title = 'Persamaan Diferensial';
            app.Panel_8 = uipanel(app.PersamaanDiferensialTab);
            app.PilihMetodeLabel_2 = uilabel(app.Panel_8);
            app.PilihMetodeLabel_2.Text = '🔘 Pilih Metode :';
            app.HitungButton_3 = uibutton(app.Panel_8, 'push');
            app.HitungButton_3.ButtonPushedFcn = createCallbackFcn(app, @HitungButton_3Pushed, true);
            app.HitungButton_3.Text = 'Hitung';
            app.ResetButton_3 = uibutton(app.Panel_8, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.KeluarButton_3 = uibutton(app.Panel_8, 'push');
            app.KeluarButton_3.Text = 'Keluar';
            app.MasukkandataLabel_3 = uilabel(app.Panel_8);
            app.MasukkandataLabel_3.Text = 'Masukkan data :';
            app.ButtonGroup_3 = uibuttongroup(app.Panel_8);
            app.EulerButton = uiradiobutton(app.ButtonGroup_3);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RungeKutta4Button = uiradiobutton(app.ButtonGroup_3);
            app.RungeKutta4Button.Text = 'Runge-Kutta 4';
            app.Y0EditFieldLabel = uilabel(app.Panel_8);
            app.Y0EditFieldLabel.Text = 'Y0 :';
            app.Y0EditField = uieditfield(app.Panel_8, 'numeric');
            app.Y0EditField.AllowEmpty = 'on';
            app.Y0EditField.Value = 1;
            app.hEditFieldLabel = uilabel(app.Panel_8);
            app.hEditFieldLabel.Text = 'h :';
            app.hEditField = uieditfield(app.Panel_8, 'numeric');
            app.hEditField.AllowEmpty = 'on';
            app.hEditField.Value = 0.5;
            app.X0Label = uilabel(app.Panel_8);
            app.X0Label.Text = 'X0 :';
            app.X0EditField = uieditfield(app.Panel_8, 'numeric');
            app.X0EditField.AllowEmpty = 'on';
            app.PersamaandydxfxyEditFieldLabel = uilabel(app.Panel_8);
            app.PersamaandydxfxyEditFieldLabel.Text = 'Persamaan dy/dx = f(x,y):';
            app.PersamaandydxfxyEditField = uieditfield(app.Panel_8, 'text');
            app.PersamaandydxfxyEditField.Value = '2x^3 - 10x^2 + 15x + 2';
            app.AlertLabel_2 = uilabel(app.Panel_8);
            app.AlertLabel_2.Text = '';
            app.XnEditFieldLabel = uilabel(app.Panel_8);
            app.XnEditFieldLabel.Text = 'Xn :';
            app.XnEditField = uieditfield(app.Panel_8, 'numeric');
            app.XnEditField.ValueDisplayFormat = '%11.6g';
            app.XnEditField.AllowEmpty = 'on';
            app.XnEditField.Value = 2;
            app.Panel_9 = uipanel(app.PersamaanDiferensialTab);
            app.UIAxes_2 = uiaxes(app.Panel_9);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HasilPerhitunganLabel_3 = uilabel(app.Panel_9);
            app.HasilPerhitunganLabel_3.Text = 'Hasil Perhitungan';
            app.UITable_2 = uitable(app.Panel_9);
            app.UITable_2.ColumnName = {'x'; 'yEuler'; 'yEksak'; 'galat'};
            app.UITable_2.RowName = {};
            app.TabelNilaixyLabel = uilabel(app.Panel_9);
            app.TabelNilaixyLabel.Interpreter = 'latex';
            app.TabelNilaixyLabel.Text = '📋 Tabel Nilai x & y';
            app.IEditField_2Label = uilabel(app.Panel_9);
            app.IEditField_2Label.Visible = 'off';
            app.IEditField_2Label.Text = 'I :';
            app.IEditField_2 = uieditfield(app.Panel_9, 'numeric');
            app.IEditField_2.ValueDisplayFormat = '%11.6g';
            app.IEditField_2.AllowEmpty = 'on';
            app.IEditField_2.Visible = 'off';
            app.GalatEditField_2Label = uilabel(app.Panel_9);
            app.GalatEditField_2Label.Visible = 'off';
            app.GalatEditField_2Label.Text = 'Galat :';
            app.GalatEditField_2 = uieditfield(app.Panel_9, 'numeric');
            app.GalatEditField_2.ValueDisplayFormat = '%11.2g';
            app.GalatEditField_2.AllowEmpty = 'on';
            app.GalatEditField_2.Visible = 'off';
            app.Label_2 = uilabel(app.Panel_9);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '%';
            app.KalkulatorPersamaanDiferensialLabel = uilabel(app.PersamaanDiferensialTab);
            app.KalkulatorPersamaanDiferensialLabel.Text = 'Kalkulator Persamaan Diferensial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_Kelompok_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end